classdef LIDAR_Visual_Vol2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DMDLIDARUIFigure         matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        ConfigurationPanel       matlab.ui.container.Panel
        SpeedEditField           matlab.ui.control.NumericEditField
        SizeYEditField           matlab.ui.control.NumericEditField
        SizeXEditField           matlab.ui.control.NumericEditField
        SizexypxLabel            matlab.ui.control.Label
        KalibrierungSliderLabel  matlab.ui.control.Label
        KalibrierungSlider       matlab.ui.control.Slider
        Spinner                  matlab.ui.control.Spinner
        UpdateSpeedsLabel        matlab.ui.control.Label
        StartStopButton          matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        i = 1 % Description
    end
    
    methods (Access = public)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Timer initialisieren
            
            timer('Name', 'timer1','timerfcn',@updatePlot,'period',app.SpeedEditField.Value,'executionmode','fixedrate','taskstoexecute',Inf);
            start(timerfind('Name', 'timer1'));
            
            
            %Timer Event definieren
            function updatePlot(src,evt)
                
                %Def Variablen für Zugriff DB
                
                conn = database('BoxiDB','pi','forscher');
                sqlquery = 'SELECT w FROM t';
                
                x = app.SizeXEditField.Value;
                y = app.SizeYEditField.Value;
                
                %Slider Skallieren
                
                app.KalibrierungSlider.Limits = [0 , x*y];
                
                
                %Daten aus DB extrahieren
                
                curs = exec(conn,sqlquery);
                setdbprefs('DataReturnFormat','cellarray')
                curs = fetch(curs);
                A =curs.Data;
                B = cell2mat(A);
                
                % Daten in Array schreiben
                
                C = zeros(y,x);
                for z = 1:y
                    for s = 1:x
                        if app.i > x*y
                            app.i = 1;
                        end    
                        C(z,s) = B(app.i,1);
                        app.i = app.i+1;
                    end    
                end
                
                %disp(A);
                %disp(C);
                
                h = imagesc(app.UIAxes,C);
                set(h,'CData', C);
                colorbar(app.UIAxes);
            end
            
        end

        % Value changing function: KalibrierungSlider
        function KalibrierungSliderValueChanging(app, event)
            changingValue = event.Value;
            app.i = round(changingValue);
            app.Spinner.Value = round(changingValue);
            
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            
            value = event.Value;
            if value > app.SizeXEditField.Value*app.SizeYEditField.Value
                value = app.SizeXEditField.Value*app.SizeYEditField.Value;
                app.Spinner.Value = app.SizeXEditField.Value*app.SizeYEditField.Value;
            end
            
            if value < 0
                value = 0;
                app.Spinner.Value = 0;
            end
            
            app.i = round(value);
            app.KalibrierungSlider.Value = value;
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            
            value = app.StartStopButton.Value;
            if value == 1
                set(timerfind('Name', 'timer1'),'period',app.SpeedEditField.Value);
                start(timerfind('Name', 'timer1'));
            else
                stop(timerfind('Name', 'timer1'));
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DMDLIDARUIFigure = uifigure;
            app.DMDLIDARUIFigure.Name = 'DMD-LIDAR';
            app.UIAxes = uiaxes(app.DMDLIDARUIFigure);
            title(app.UIAxes, '3D Scan, gemessener Abstand [mm]')
            xlabel(app.UIAxes, 'width [°]')
            ylabel(app.UIAxes, 'height [°]')
            app.ConfigurationPanel = uipanel(app.DMDLIDARUIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.SpeedEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.SpeedEditField.Value = 0.2;
            app.SizeYEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.SizeYEditField.Value = 30;
            app.SizeXEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.SizeXEditField.Value = 70;
            app.SizexypxLabel = uilabel(app.ConfigurationPanel);
            app.SizexypxLabel.Text = 'Size (x/y) [px] :';
            app.KalibrierungSliderLabel = uilabel(app.ConfigurationPanel);
            app.KalibrierungSliderLabel.Text = 'Kalibrierung';
            app.KalibrierungSlider = uislider(app.ConfigurationPanel);
            app.KalibrierungSlider.Limits = [0 2100];
            app.KalibrierungSlider.ValueChangingFcn = createCallbackFcn(app, @KalibrierungSliderValueChanging, true);
            app.Spinner = uispinner(app.ConfigurationPanel);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.UpdateSpeedsLabel = uilabel(app.ConfigurationPanel);
            app.UpdateSpeedsLabel.Text = 'Update Speed [s] :';
            app.StartStopButton = uibutton(app.ConfigurationPanel, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.StartStopButton.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = LIDAR_Visual_Vol2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DMDLIDARUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DMDLIDARUIFigure)
        end
    end
end